classdef guiTest < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        SystemStartButton               matlab.ui.control.Button
        UR3SprayPainterPanel            matlab.ui.container.Panel
        SendCartesianCoordinatesButton_2  matlab.ui.control.Button
        Start2Button_2                  matlab.ui.control.Button
        Start1Button_2                  matlab.ui.control.Button
        zSpinner_2                      matlab.ui.control.Spinner
        zSpinner_2Label                 matlab.ui.control.Label
        ySpinner_2                      matlab.ui.control.Spinner
        ySpinner_2Label                 matlab.ui.control.Label
        xSpinner_2                      matlab.ui.control.Spinner
        xSpinner_2Label                 matlab.ui.control.Label
        Joint6Spinner_2                 matlab.ui.control.Spinner
        Joint6Spinner_2Label            matlab.ui.control.Label
        Joint5Spinner_2                 matlab.ui.control.Spinner
        Joint5Spinner_2Label            matlab.ui.control.Label
        Joint3Spinner_2                 matlab.ui.control.Spinner
        Joint3Spinner_2Label            matlab.ui.control.Label
        Joint4Spinner_2                 matlab.ui.control.Spinner
        Joint4Spinner_2Label            matlab.ui.control.Label
        Joint2Spinner_2                 matlab.ui.control.Spinner
        Joint2Spinner_2Label            matlab.ui.control.Label
        Joint1Spinner_2                 matlab.ui.control.Spinner
        Joint1Spinner_2Label            matlab.ui.control.Label
        STOPButton_2                    matlab.ui.control.Button
        Auboi3PaperHolderPanel          matlab.ui.container.Panel
        SendCartesianCoordinatesButton  matlab.ui.control.Button
        Start2Button                    matlab.ui.control.Button
        Start1Button                    matlab.ui.control.Button
        zSpinner                        matlab.ui.control.Spinner
        zSpinnerLabel                   matlab.ui.control.Label
        ySpinner                        matlab.ui.control.Spinner
        ySpinnerLabel                   matlab.ui.control.Label
        xSpinner                        matlab.ui.control.Spinner
        xSpinnerLabel                   matlab.ui.control.Label
        Joint6Spinner                   matlab.ui.control.Spinner
        Joint6SpinnerLabel              matlab.ui.control.Label
        Joint5Spinner                   matlab.ui.control.Spinner
        Joint5SpinnerLabel              matlab.ui.control.Label
        Joint3Spinner                   matlab.ui.control.Spinner
        Joint3SpinnerLabel              matlab.ui.control.Label
        Joint4Spinner                   matlab.ui.control.Spinner
        Joint4SpinnerLabel              matlab.ui.control.Label
        Joint2Spinner                   matlab.ui.control.Spinner
        Joint2SpinnerLabel              matlab.ui.control.Label
        Joint1Spinner                   matlab.ui.control.Spinner
        Joint1SpinnerLabel              matlab.ui.control.Label
        STOPButton                      matlab.ui.control.Button
    end

    properties (Access = public)
        aubo_q1 (1,1) double = 0; % Joint value for aubo q1
        aubo_q2 (1,1) double % aubo q2
        aubo_q3 (1,1) double % aubo i3 q value
        aubo_q4 (1,1) = 0;
        aubo_q5 (1,1) = 0;
        aubo_q6 (1,1) = 0;
        aubo_start1 (1,1) logical = 0; % Post emergency stop start button 1 (must be pressed before start button 2)
        aubo_start2 (1,1) logical = 0;
        aubo_stop (1,1) logical = 0;
        aubo_x (1,1) = 0;
        aubo_y (1,1) = 0;
        aubo_z (1,1) = 0;
        system_on (1,1) logical = 0;
        ur3_start1 (1,1) logical = 0;
        ur3_start2 (1,1) logical = 0;
        ur3_stop (1,1) logical = 0;
        ur_q1 (1,1) = 0;
        ur_q2 (1,1) = 0;
        ur_q3 (1,1) = 0;
        ur_q4 (1,1) = 0;
        ur_q5 (1,1) = 0;
        ur_q6 (1,1) = 0;
        ur_x (1,1) = 0;
        ur_y (1,1) = 0;
        ur_z (1,1) = 0;
    end
    
    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Joint1Spinner
        function Joint1SpinnerValueChanged(comp, event)
            comp.aubo_q1 = comp.Joint1Spinner.Value;
            assignin("base", 'aubo_q1', comp.aubo_q1)
            
        end

        % Value changed function: Joint2Spinner
        function Joint2SpinnerValueChanged(comp, event)
            comp.aubo_q2 = comp.Joint2Spinner.Value;
            assignin("base", 'aubo_q2', comp.aubo_q2)
        end

        % Value changed function: Joint3Spinner
        function Joint3SpinnerValueChanged(comp, event)
            comp.aubo_q3 = comp.Joint3Spinner.Value;
            assignin("base", 'aubo_q3', comp.aubo_q3)
            
        end

        % Value changed function: Joint4Spinner
        function Joint4SpinnerValueChanged(comp, event)
            comp.aubo_q4 = comp.Joint4Spinner.Value;
            assignin("base", 'aubo_q4', comp.aubo_q4)
        end

        % Value changed function: Joint5Spinner
        function Joint5SpinnerValueChanged(comp, event)
            comp.aubo_q5 = comp.Joint5Spinner.Value;
            assignin("base", 'aubo_q5', comp.aubo_q5)
            
        end

        % Value changed function: Joint6Spinner
        function Joint6SpinnerValueChanged(comp, event)
            comp.aubo_q6 = comp.Joint6Spinner.Value;
            assignin("base", 'aubo_q6', comp.aubo_q6)
            
        end

        % Value changed function: xSpinner
        function xSpinnerValueChanged(comp, event)
            comp.aubo_x = comp.xSpinner.Value;
%             assignin("base", 'aubo_x', comp.aubo_x)
            
        end

        % Value changed function: ySpinner
        function ySpinnerValueChanged(comp, event)
            comp.aubo_y = comp.ySpinner.Value;
%             assignin("base", 'aubo_y', comp.aubo_y)
            
        end

        % Value changed function: zSpinner
        function zSpinnerValueChanged(comp, event)
            comp.aubo_z = comp.zSpinner.Value;
%             assignin("base", 'aubo_z', comp.aubo_z)
            
        end

        % Value changed function: Joint1Spinner_2
        function Joint1Spinner_2ValueChanged(comp, event)
            comp.ur_q1 = comp.Joint1Spinner_2.Value;
            assignin("base", 'ur_q1', comp.ur_q1)
            
        end

        % Value changed function: Joint2Spinner_2
        function Joint2Spinner_2ValueChanged(comp, event)
            comp.ur_q2 = comp.Joint2Spinner_2.Value;
            assignin("base", 'ur_q2', comp.ur_q2)
        end

        % Value changed function: Joint4Spinner_2
        function Joint4Spinner_2ValueChanged(comp, event)
            comp.ur_q4 = comp.Joint4Spinner_2.Value;
            assignin("base", 'ur_q4', comp.ur_q4)
            
        end

        % Value changed function: Joint3Spinner_2
        function Joint3Spinner_2ValueChanged(comp, event)
            comp.ur_q3 = comp.Joint3Spinner_2.Value;
            assignin("base", 'ur_q3', comp.ur_q3)
        end

        % Value changed function: Joint5Spinner_2
        function Joint5Spinner_2ValueChanged(comp, event)
            comp.ur_q5 = comp.Joint5Spinner_2.Value;
            assignin("base", 'ur_q5', comp.ur_q5)
        end

        % Value changed function: Joint6Spinner_2
        function Joint6Spinner_2ValueChanged(comp, event)
            comp.ur_q6 = comp.Joint6Spinner_2.Value;
            assignin("base", 'ur_q6', comp.ur_q6)
            
        end

        % Value changed function: xSpinner_2
        function xSpinner_2ValueChanged(comp, event)
            comp.ur_x = comp.xSpinner_2.Value;
%             assignin("base", 'ur_x', comp.ur_x)
            
        end

        % Value changed function: ySpinner_2
        function ySpinner_2ValueChanged(comp, event)
            comp.ur_y = comp.ySpinner_2.Value;
%             assignin("base", 'ur_y', comp.ur_y)
            
        end

        % Value changed function: zSpinner_2
        function zSpinner_2ValueChanged(comp, event)
            comp.ur_z = comp.zSpinner_2.Value;
%             assignin("base", 'ur_z', comp.ur_z)
            
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(comp, event)
            comp.aubo_stop = 1
            assignin("base", 'aubo_stop_on', comp.aubo_stop)
            comp.aubo_start1 = 0
            assignin("base", 'aubo_start1_on', comp.aubo_start1)
            comp.aubo_start2 = 0
            assignin("base", 'aubo_start2_on', comp.aubo_start2)
        end

        % Button pushed function: STOPButton_2
        function STOPButton_2Pushed(comp, event)
            comp.ur3_stop = 1
            assignin("base", 'ur3_stop_on', comp.ur3_stop)
            comp.ur3_start1 = 0
            assignin("base", 'ur3_start1_on', comp.ur3_start1)
            comp.ur3_start2 = 0
            assignin("base", 'ur3_start2_on', comp.ur3_start2)


        end

        % Button pushed function: Start1Button
        function Start1ButtonPushed(comp, event)
            if (comp.aubo_stop == 1)
                comp.aubo_start1 = 1
                assignin("base", 'aubo_start1_on', comp.aubo_start1)
            else
                comp.aubo_start1 = 0
                assignin("base", 'aubo_start1_on', comp.aubo_start1)
                comp.aubo_stop = 0
                assignin("base", 'aubo_stop_on', comp.aubo_stop)
                comp.aubo_start2 = 0
                assignin("base", 'aubo_start2_on', comp.aubo_start2)
            end
        end

        % Button pushed function: Start2Button
        function Start2ButtonPushed(comp, event)
            if (comp.aubo_start1 == 1)
                comp.aubo_start2 = 1
                assignin("base", 'aubo_start2_on', comp.aubo_start2)
                comp.aubo_stop = 0
                assignin("base", 'aubo_stop_on', comp.aubo_stop)

            else
                comp.aubo_start2 = 0
                assignin("base", 'aubo_start2_on', comp.aubo_start2)
                comp.aubo_start1 = 0
                assignin("base", 'aubo_start1_on', comp.aubo_start1)
            end
        end

        % Button pushed function: Start1Button_2
        function Start1Button_2Pushed(comp, event)
            if (comp.ur3_stop == 1)
                comp.ur3_start1 = 1
                assignin("base", 'ur3_start1_on', comp.ur3_start1)
            else
                comp.ur3_start1 = 0
                assignin("base", 'ur3_start1_on', comp.ur3_start1)
                comp.ur3_stop = 0
                assignin("base", 'ur3_stop_on', comp.ur3_stop)
                comp.ur3_start2 = 0
                assignin("base", 'ur3_start2_on', comp.ur3_start2)
            end
        end

        % Button pushed function: Start2Button_2
        function Start2Button_2Pushed(comp, event)
           if (comp.ur3_start1 == 1)
                comp.ur3_start2 = 1
                assignin("base", 'ur3_start2_on', comp.ur3_start2)
                comp.ur3_stop = 0
                assignin("base", 'ur3_stop_on', comp.ur3_stop)

            else
                comp.ur3_start2 = 0
                assignin("base", 'ur3_start2_on', comp.ur3_start2)
                comp.ur3_start1 = 0
                assignin("base", 'ur3_start1_on', comp.ur3_start1)
            end 
        end

        % Button pushed function: SystemStartButton
        function SystemStartButtonPushed(comp, event)
            comp.system_on = 1
            assignin("base", 'system_on', comp.system_on)
            assignin("base", 'aubo_q1', comp.aubo_q1)
            assignin("base", 'aubo_q2', comp.aubo_q2)
            assignin("base", 'aubo_q3', comp.aubo_q3)
            assignin("base", 'aubo_q4', comp.aubo_q4)
            assignin("base", 'aubo_q5', comp.aubo_q5)
            assignin("base", 'aubo_q6', comp.aubo_q6)
            assignin("base", 'ur_q1', comp.ur_q1)
            assignin("base", 'ur_q2', comp.ur_q2)
            assignin("base", 'ur_q3', comp.ur_q3)
            assignin("base", 'ur_q4', comp.ur_q4)
            assignin("base", 'ur_q5', comp.ur_q5)
            assignin("base", 'ur_q6', comp.ur_q6)
%             assignin("base", 'aubo_x', comp.aubo_x)
%             assignin("base", 'aubo_y', comp.aubo_y)
%             assignin("base", 'aubo_z', comp.aubo_z)
%             assignin("base", 'ur_x', comp.ur_x)
%             assignin("base", 'ur_y', comp.ur_y)
%             assignin("base", 'ur_z', comp.ur_z)
            assignin("base", 'aubo_stop_on', comp.aubo_stop)
            
            assignin("base", 'aubo_start1_on', comp.aubo_start1)
            
            assignin("base", 'aubo_start2_on', comp.aubo_start2)


        end

        % Button pushed function: SendCartesianCoordinatesButton
        function SendCartesianCoordinatesButtonPushed(comp, event)
            assignin("base", 'aubo_x', comp.aubo_x)
            assignin("base", 'aubo_y', comp.aubo_y)
            assignin("base", 'aubo_z', comp.aubo_z)
        end

        % Button pushed function: SendCartesianCoordinatesButton_2
        function SendCartesianCoordinatesButton_2Pushed(comp, event)
            assignin("base", 'ur_x', comp.ur_x)
            assignin("base", 'ur_y', comp.ur_y)
            assignin("base", 'ur_z', comp.ur_z)
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 851 741];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.Auboi3PaperHolderPanel = uipanel(comp);
            comp.Auboi3PaperHolderPanel.Title = 'Aubo i3 (Paper Holder)';
            comp.STOPButton = uibutton(comp.Auboi3PaperHolderPanel, 'push');
            comp.STOPButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @STOPButtonPushed, true);
            comp.STOPButton.Text = 'STOP';
            comp.Joint1SpinnerLabel = uilabel(comp.Auboi3PaperHolderPanel);
            comp.Joint1SpinnerLabel.Text = 'Joint 1';
            comp.Joint1Spinner = uispinner(comp.Auboi3PaperHolderPanel);
            comp.Joint1Spinner.Limits = [-360 360];
            comp.Joint1Spinner.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @Joint1SpinnerValueChanged, true);
            comp.Joint2SpinnerLabel = uilabel(comp.Auboi3PaperHolderPanel);
            comp.Joint2SpinnerLabel.Text = 'Joint 2';
            comp.Joint2Spinner = uispinner(comp.Auboi3PaperHolderPanel);
            comp.Joint2Spinner.Limits = [-360 360];
            comp.Joint2Spinner.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @Joint2SpinnerValueChanged, true);
            comp.Joint4SpinnerLabel = uilabel(comp.Auboi3PaperHolderPanel);
            comp.Joint4SpinnerLabel.Text = 'Joint 4';
            comp.Joint4Spinner = uispinner(comp.Auboi3PaperHolderPanel);
            comp.Joint4Spinner.Limits = [-360 360];
            comp.Joint4Spinner.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @Joint4SpinnerValueChanged, true);
            comp.Joint3SpinnerLabel = uilabel(comp.Auboi3PaperHolderPanel);
            comp.Joint3SpinnerLabel.Text = 'Joint 3';
            comp.Joint3Spinner = uispinner(comp.Auboi3PaperHolderPanel);
            comp.Joint3Spinner.Limits = [-360 360];
            comp.Joint3Spinner.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @Joint3SpinnerValueChanged, true);
            comp.Joint5SpinnerLabel = uilabel(comp.Auboi3PaperHolderPanel);
            comp.Joint5SpinnerLabel.Text = 'Joint 5';
            comp.Joint5Spinner = uispinner(comp.Auboi3PaperHolderPanel);
            comp.Joint5Spinner.Limits = [-360 360];
            comp.Joint5Spinner.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @Joint5SpinnerValueChanged, true);
            comp.Joint6SpinnerLabel = uilabel(comp.Auboi3PaperHolderPanel);
            comp.Joint6SpinnerLabel.Text = 'Joint 6';
            comp.Joint6Spinner = uispinner(comp.Auboi3PaperHolderPanel);
            comp.Joint6Spinner.Limits = [-360 360];
            comp.Joint6Spinner.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @Joint6SpinnerValueChanged, true);
            comp.xSpinnerLabel = uilabel(comp.Auboi3PaperHolderPanel);
            comp.xSpinnerLabel.Text = 'x';
            comp.xSpinner = uispinner(comp.Auboi3PaperHolderPanel);
            comp.xSpinner.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @xSpinnerValueChanged, true);
            comp.ySpinnerLabel = uilabel(comp.Auboi3PaperHolderPanel);
            comp.ySpinnerLabel.Text = 'y';
            comp.ySpinner = uispinner(comp.Auboi3PaperHolderPanel);
            comp.ySpinner.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @ySpinnerValueChanged, true);
            comp.zSpinnerLabel = uilabel(comp.Auboi3PaperHolderPanel);
            comp.zSpinnerLabel.Text = 'z';
            comp.zSpinner = uispinner(comp.Auboi3PaperHolderPanel);
            comp.zSpinner.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @zSpinnerValueChanged, true);
            comp.Start1Button = uibutton(comp.Auboi3PaperHolderPanel, 'push');
            comp.Start1Button.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Start1ButtonPushed, true);
            comp.Start1Button.Text = 'Start 1';
            comp.Start2Button = uibutton(comp.Auboi3PaperHolderPanel, 'push');
            comp.Start2Button.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Start2ButtonPushed, true);
            comp.Start2Button.Text = 'Start 2';
            comp.SendCartesianCoordinatesButton = uibutton(comp.Auboi3PaperHolderPanel, 'push');
            comp.SendCartesianCoordinatesButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SendCartesianCoordinatesButtonPushed, true);
            comp.SendCartesianCoordinatesButton.Text = 'Send Cartesian Coordinates';
            comp.UR3SprayPainterPanel = uipanel(comp);
            comp.UR3SprayPainterPanel.Title = 'UR3 (Spray Painter)';
            comp.STOPButton_2 = uibutton(comp.UR3SprayPainterPanel, 'push');
            comp.STOPButton_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @STOPButton_2Pushed, true);
            comp.STOPButton_2.Text = 'STOP';
            comp.Joint1Spinner_2Label = uilabel(comp.UR3SprayPainterPanel);
            comp.Joint1Spinner_2Label.Text = 'Joint 1';
            comp.Joint1Spinner_2 = uispinner(comp.UR3SprayPainterPanel);
            comp.Joint1Spinner_2.Limits = [-360 360];
            comp.Joint1Spinner_2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @Joint1Spinner_2ValueChanged, true);
            comp.Joint2Spinner_2Label = uilabel(comp.UR3SprayPainterPanel);
            comp.Joint2Spinner_2Label.Text = 'Joint 2';
            comp.Joint2Spinner_2 = uispinner(comp.UR3SprayPainterPanel);
            comp.Joint2Spinner_2.Limits = [-360 360];
            comp.Joint2Spinner_2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @Joint2Spinner_2ValueChanged, true);
            comp.Joint4Spinner_2Label = uilabel(comp.UR3SprayPainterPanel);
            comp.Joint4Spinner_2Label.Text = 'Joint 4';
            comp.Joint4Spinner_2 = uispinner(comp.UR3SprayPainterPanel);
            comp.Joint4Spinner_2.Limits = [-360 360];
            comp.Joint4Spinner_2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @Joint4Spinner_2ValueChanged, true);
            comp.Joint3Spinner_2Label = uilabel(comp.UR3SprayPainterPanel);
            comp.Joint3Spinner_2Label.Text = 'Joint 3';
            comp.Joint3Spinner_2 = uispinner(comp.UR3SprayPainterPanel);
            comp.Joint3Spinner_2.Limits = [-360 360];
            comp.Joint3Spinner_2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @Joint3Spinner_2ValueChanged, true);
            comp.Joint5Spinner_2Label = uilabel(comp.UR3SprayPainterPanel);
            comp.Joint5Spinner_2Label.Text = 'Joint 5';
            comp.Joint5Spinner_2 = uispinner(comp.UR3SprayPainterPanel);
            comp.Joint5Spinner_2.Limits = [-360 360];
            comp.Joint5Spinner_2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @Joint5Spinner_2ValueChanged, true);
            comp.Joint6Spinner_2Label = uilabel(comp.UR3SprayPainterPanel);
            comp.Joint6Spinner_2Label.Text = 'Joint 6';
            comp.Joint6Spinner_2 = uispinner(comp.UR3SprayPainterPanel);
            comp.Joint6Spinner_2.Limits = [-360 360];
            comp.Joint6Spinner_2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @Joint6Spinner_2ValueChanged, true);
            comp.xSpinner_2Label = uilabel(comp.UR3SprayPainterPanel);
            comp.xSpinner_2Label.Text = 'x';
            comp.xSpinner_2 = uispinner(comp.UR3SprayPainterPanel);
            comp.xSpinner_2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @xSpinner_2ValueChanged, true);
            comp.ySpinner_2Label = uilabel(comp.UR3SprayPainterPanel);
            comp.ySpinner_2Label.Text = 'y';
            comp.ySpinner_2 = uispinner(comp.UR3SprayPainterPanel);
            comp.ySpinner_2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @ySpinner_2ValueChanged, true);
            comp.zSpinner_2Label = uilabel(comp.UR3SprayPainterPanel);
            comp.zSpinner_2Label.Text = 'z';
            comp.zSpinner_2 = uispinner(comp.UR3SprayPainterPanel);
            comp.zSpinner_2.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @zSpinner_2ValueChanged, true);
            comp.Start1Button_2 = uibutton(comp.UR3SprayPainterPanel, 'push');
            comp.Start1Button_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Start1Button_2Pushed, true);
            comp.Start1Button_2.Text = 'Start 1';
            comp.Start2Button_2 = uibutton(comp.UR3SprayPainterPanel, 'push');
            comp.Start2Button_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @Start2Button_2Pushed, true);
            comp.Start2Button_2.Text = 'Start 2';
            comp.SendCartesianCoordinatesButton_2 = uibutton(comp.UR3SprayPainterPanel, 'push');
            comp.SendCartesianCoordinatesButton_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SendCartesianCoordinatesButton_2Pushed, true);
            comp.SendCartesianCoordinatesButton_2.Text = 'Send Cartesian Coordinates';
            comp.SystemStartButton = uibutton(comp, 'push');
            comp.SystemStartButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SystemStartButtonPushed, true);
            comp.SystemStartButton.Text = 'System Start';
        end
    end
end